classdef UIPassword < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SecurityLabelType           matlab.ui.control.Label
        SecurityLabel               matlab.ui.control.Label
        Update                      matlab.ui.control.Button
        Copy                        matlab.ui.control.Button
        LengthSlider                matlab.ui.control.Slider
        LengthLabel                 matlab.ui.control.Label
        PasswordconfigurationLabel  matlab.ui.control.Label
        Length                      matlab.ui.control.NumericEditField
        UppercaseCheckBox           matlab.ui.control.CheckBox
        LowercaseCheckBox           matlab.ui.control.CheckBox
        NumbersCheckBox             matlab.ui.control.CheckBox
        SymbolsCheckBox             matlab.ui.control.CheckBox
        TextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        up = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ'
        low = 'abcdefghijklmnopqrstuvwxyz'
        numbers = '0123456789'
        special = '!@#$%^&*_-+=:;.,'
        weak = [196 71 96]/255
        medium = [235 176 99]/255
        strong = [162 200 176]/255
        epic = [66 68 85]/255
    end
    
    methods (Access = private)
        
        function set_length_key(app, length_pass)
            % Set length pass
            app.Length.Value = length_pass;
        end

        function length_key = get_length_key(app)
            % Get length pass
            length_key = app.Length.Value;
        end

        function set_key(app, key)
            % Assign generated key
            app.TextArea.Value = key;
        end
        
        function [key, length_key] = get_key(app)
            % Get key
            key = app.TextArea.Value{:};
            length_key = length(key);
        end

        function sequence = get_sequence(app)
            % Get sequence to generate the password

            % Initialize
            sequence = [];
            % Check conditions
            if app.UppercaseCheckBox.Value
                sequence = [sequence, app.up];
            end
            if app.LowercaseCheckBox.Value
                sequence = [sequence, app.low];
            end
            if app.NumbersCheckBox.Value
                sequence = [sequence, app.numbers];
            end
            if app.SymbolsCheckBox.Value
                sequence = [sequence, app.special];
            end
            if isempty(sequence)
                message = {'Please, choose characters for the password (e.g., Numbers)'};
                uialert(app.UIFigure, message, 'Warning', 'Icon', 'warning');
                sequence = app.numbers;
            end
        end

        function sample = random_sample(app, sequence, length_sequence)
            % Returns a particular length list of items chosen from the sequence
            index = randi([1, length(sequence)], 1, length_sequence);
            sample = sequence(index);
        end
        
        function key = generate_key(app)
            % Generate a random password
            
            % Get length
            length_pass = get_length_key(app);
            % Get sequence
            sequence = get_sequence(app);
            % Get random key
            key = random_sample(app, sequence, length_pass);
        end

        function type = analyse_security(app, key)
            % Change background color from TextArea depending on how secure is
            % the password
            
            % Definitions
            LENGTH_PASS = length(key);
            FLAG_UP = any(ismember(key, app.up));
            FLAG_LOW = any(ismember(key, app.low));
            FLAG_NUMBERS = any(ismember(key, app.numbers));
            FLAG_SYMBOLS = any(ismember(key, app.special));
            % * Epic
            cond_epic_1 = FLAG_UP && FLAG_LOW && LENGTH_PASS >= 18;
            cond_epic_2 = FLAG_UP && FLAG_LOW && FLAG_NUMBERS && LENGTH_PASS >= 17;
            cond_epic_3 = FLAG_UP && FLAG_LOW && FLAG_NUMBERS && FLAG_SYMBOLS && LENGTH_PASS >= 16;
            % * Strong
            cond_strong_1 = FLAG_LOW && LENGTH_PASS >= 14;
            cond_strong_2 = FLAG_UP && FLAG_LOW && LENGTH_PASS >= 12;
            cond_strong_3 = FLAG_UP && FLAG_LOW && FLAG_NUMBERS && LENGTH_PASS >= 11;
            cond_strong_4 = FLAG_UP && FLAG_LOW && FLAG_NUMBERS && FLAG_SYMBOLS && LENGTH_PASS >= 11;
            % * Medium
            cond_medium_1 = FLAG_NUMBERS && LENGTH_PASS >= 12;
            cond_medium_2 = FLAG_LOW && LENGTH_PASS >= 9;
            cond_medium_3 = FLAG_UP && FLAG_LOW && LENGTH_PASS >= 7;
            cond_medium_4 = FLAG_UP && FLAG_LOW && FLAG_NUMBERS && LENGTH_PASS >= 7;
            cond_medium_5 = FLAG_UP && FLAG_LOW && FLAG_NUMBERS && FLAG_SYMBOLS && LENGTH_PASS >= 7;
            % Check conditions from epic to medium, otherwise weak
            if cond_epic_1 || cond_epic_2 || cond_epic_3
                type = 'epic';
            elseif cond_strong_1 || cond_strong_2 || cond_strong_3 || cond_strong_4
                type = 'strong';
            elseif cond_medium_1 || cond_medium_2 || cond_medium_3 || cond_medium_4 || cond_medium_5
                type = 'medium';
            else
                type = 'weak';
            end
%             % Update GUI
%             app.UppercaseCheckBox.Value = FLAG_UP;
%             app.LowercaseCheckBox.Value = FLAG_LOW;
%             app.NumbersCheckBox.Value = FLAG_NUMBERS;
%             app.SymbolsCheckBox.Value = FLAG_SYMBOLS;
        end

        function change_bg_color_obj(app, obj, type)
            % Change background color of the object

            % Get color from properties
            color = app.(type);
            % Change background color
            app.(obj).BackgroundColor = color;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Generate key
            key = generate_key(app);
            % Check how secure is the random key
            type = analyse_security(app, key);
            % Update GUI
            set_key(app, key);
            app.SecurityLabelType.Text = type;
            change_bg_color_obj(app, 'TextArea', type)
        end

        % Button pushed function: Copy
        function CopyPushed(app, event)
            clipboard('copy', app.TextArea.Value{:});
        end

        % Value changed function: Length
        function LengthValueChanged(app, event)
            % Get value
            app.Length.Value = event.Value;
            % Update slider
            app.LengthSlider.Value = app.Length.Value;
            % Generate key
            startupFcn(app);
        end

        % Value changing function: LengthSlider
        function LengthSliderValueChanging(app, event)
            LengthValueChanged(app, event);
        end

        % Button pushed function: Update
        function UpdateButtonPushed(app, event)
            startupFcn(app);
        end

        % Value changing function: TextArea
        function TextAreaValueChanging(app, event)
            % Update text from event
            app.TextArea.Value = event.Value;
            % Get key
            [key, length_key] = get_key(app);
            % Check how secure is the random key
            type = analyse_security(app, key);
            % Update GUI
            set_length_key(app, length_key)
            app.SecurityLabelType.Text = type;
            change_bg_color_obj(app, 'TextArea', type)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UIPassword';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'password-reset.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.TextArea.Tooltip = {''};
            app.SymbolsCheckBox = uicheckbox(app.UIFigure);
            app.SymbolsCheckBox.Text = 'Symbols';
            app.SymbolsCheckBox.Value = true;
            app.NumbersCheckBox = uicheckbox(app.UIFigure);
            app.NumbersCheckBox.Text = 'Numbers';
            app.NumbersCheckBox.Value = true;
            app.LowercaseCheckBox = uicheckbox(app.UIFigure);
            app.LowercaseCheckBox.Text = 'Lower case';
            app.LowercaseCheckBox.Value = true;
            app.UppercaseCheckBox = uicheckbox(app.UIFigure);
            app.UppercaseCheckBox.Text = 'Upper case';
            app.UppercaseCheckBox.Value = true;
            app.Length = uieditfield(app.UIFigure, 'numeric');
            app.Length.Limits = [1 50];
            app.Length.RoundFractionalValues = 'on';
            app.Length.ValueDisplayFormat = '%11d';
            app.Length.ValueChangedFcn = createCallbackFcn(app, @LengthValueChanged, true);
            app.Length.Value = 12;
            app.PasswordconfigurationLabel = uilabel(app.UIFigure);
            app.PasswordconfigurationLabel.Text = 'Password configuration';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length:';
            app.LengthSlider = uislider(app.UIFigure);
            app.LengthSlider.Limits = [1 50];
            app.LengthSlider.MajorTicks = [];
            app.LengthSlider.MajorTickLabels = {''};
            app.LengthSlider.ValueChangingFcn = createCallbackFcn(app, @LengthSliderValueChanging, true);
            app.LengthSlider.MinorTicks = [];
            app.LengthSlider.Value = 12;
            app.Copy = uibutton(app.UIFigure, 'push');
            app.Copy.ButtonPushedFcn = createCallbackFcn(app, @CopyPushed, true);
            app.Copy.Icon = fullfile(pathToMLAPP, 'icons', 'copy-svgrepo-com.svg');
            app.Copy.Tooltip = {'Copy'};
            app.Copy.Text = '';
            app.Update = uibutton(app.UIFigure, 'push');
            app.Update.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.Update.Icon = fullfile(pathToMLAPP, 'icons', 'update-svgrepo-com.svg');
            app.Update.Tooltip = {'Generate'};
            app.Update.Text = '';
            app.SecurityLabel = uilabel(app.UIFigure);
            app.SecurityLabel.Text = 'Security:';
            app.SecurityLabelType = uilabel(app.UIFigure);
            app.SecurityLabelType.Text = 'strong';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UIPassword

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end